classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BadaniestacjonarnocisygnauMenu  matlab.ui.container.Menu
        StacjonarnyLampLabel            matlab.ui.control.Label
        StacjonarnyLamp                 matlab.ui.control.Lamp
        NiestacjonarnyLampLabel         matlab.ui.control.Label
        NiestacjonarnyLamp              matlab.ui.control.Lamp
        SliceButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        BadanysygnaTab                  matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        Fragment1Tab                    matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        Fragment2Tab                    matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        Fragment3Tab                    matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        Fragment4Tab                    matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        ImportujsygnaButton             matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        WyjdButton                      matlab.ui.control.Button
        NakadkowanieSpinnerLabel        matlab.ui.control.Label
        NakadkowanieSpinner             matlab.ui.control.Spinner
        BadanysygnaDropDownLabel        matlab.ui.control.Label
        BadanysygnaDropDown             matlab.ui.control.DropDown
        BadadniestacjonarnocisygnauLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        SposbwyborusygnauButtonGroup    matlab.ui.container.ButtonGroup
        SygnademonstracyjnyButton       matlab.ui.control.RadioButton
        SygnawasnyButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        det % Zmienna logiczna określająca stacjonarność sygnału
        x % Badany sygnał
        t % wektor czasu
        file = "poli.mat"; % wczytywany plik
        bufor = 'poli.mat';
        tab_data
    end
    
    methods (Access = private)
        
        
        function sliced = slice(~,x, n, e)
        sliced = [];
[m ,l] = size(x);
if (m >1 && l >1)
error('Badany sygnał musi zostać podany w postaci jednowymiarowego wektora')
end
if isa(n,'string)') || isa(n,'char)')
   error('Drugi argument funkcji nie może być typu string lub char')
elseif isa(n, 'table')
    error('Drugi argument funkcji nie może być tablicą')
elseif isa(n, 'struct')
    error('Drugi argument funkcji nie może być strukturą')
end
if(n < 1 )
    error('Drugi parametr musi być dodatnią liczbą całkowitą') 
end
if isa(e,'string)') || isa(e,'char)')
   error('Drugi argument funkcji nie może być typu string lub char')
elseif isa(e, 'table')
    error('Drugi argument funkcji nie może być tablicą')
elseif isa(e, 'struct')
    error('Drugi argument funkcji nie może być struturą')
elseif(e < 0 || e >=1 )
    error('Trzeci parametr musi zawierać się w przedziale [0, 1)') 
end

sig_len = length(x);
param = (1 - e);
slice_len = floor(sig_len / ((1 - e) * double(n) + e));

for i = 1 : n
    if(i == 1)
       sliced(i, :) = x(1 : slice_len + 1 );
    else
      try sliced(i, :) = x(floor((i-1) * param * slice_len) : floor((1 + (i-1) *param) * slice_len));
          
      catch 
          sliced(i, :) = x(floor((i-1) * param * slice_len) : floor((1 + (i-1) *param) * slice_len) - 1);
      end
    end
end

        end
        
        function [info, tab_data] = determine(~,x,sliced, percent, nofrag)
if isa(percent,'string)') || isa(percent,'char)')
   error('Trzeci argument funkcji nie może być typu string lub char')
elseif isa(percent, 'table')
    error('Trzeci argument funkcji nie może być tablicą')
elseif isa(percent, 'struct')
    error('Trzeci argument funkcji nie może być struturą')
elseif(percent > 1 || percent < 0)
    error('Trzeci argument musi znajdować się w przedziale (0, 1)')
end

[m1 ,n1] = size(x);
if (m1 >1 && n1 >1)
error('Badany sygnał musi mieć postać jednowymiarowego wektora')
end

[m2 ,n2] = size(sliced);
if (~(m2 == nofrag))
error('Podzielony sygnał musi mieć postać cztero-wierszowej macierzy')
end


info = false;
m_sig = parameters(x);
tab_data(1,1) = m_sig.expected_value;
tab_data(2,1) = m_sig.root_mean_square;
tab_data(3,1) = m_sig.standard_deviation;
tab_data(4,1) = m_sig.variance;
tab_data(5,1) = m_sig.skewness;
tab_data(6,1) = m_sig.kurtosis;

for i = 1 : length(sliced(:,1))
s_sig = parameters(sliced(i, :));
tab_data(1,i+1) = s_sig.expected_value;
tab_data(2,i+1) = s_sig.root_mean_square;
tab_data(3,i+1) = s_sig.standard_deviation;
tab_data(4,i+1) = s_sig.variance;
tab_data(5,i+1) = s_sig.skewness;
tab_data(6,i+1) = s_sig.kurtosis;
end

for i = 1 : length(sliced(:,1))
s_sig = parameters(sliced(i, :));
cond_1 = range_fit(m_sig.expected_value, s_sig.expected_value, percent);
cond_2 = range_fit(m_sig.root_mean_square, s_sig.root_mean_square, percent);
cond_3 = range_fit(m_sig.standard_deviation, s_sig.standard_deviation, percent);
cond_4 = range_fit(m_sig.variance, s_sig.variance, percent);
cond_5 = range_fit(m_sig.skewness, s_sig.skewness, percent);
cond_6 = range_fit(m_sig.kurtosis, s_sig.kurtosis, percent);


if( cond_1 && cond_2 && cond_3 && cond_4 && cond_6 )
    info = true;
else
    info = false;
    break ;
end
end
        end
        
        
        function [] = cleans(app)
            app.NakadkowanieSpinner.Value = 0.25;
            app.StacjonarnyLamp.Enable = 'off';
            app.NiestacjonarnyLamp.Enable = 'off';
            app.UIAxes.Visible = 'off';
            app.UIAxes5.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes.cla ;
            app.UIAxes5.cla ;
            app.UIAxes4.cla ;
            app.UIAxes3.cla ;
            app.UIAxes2.cla ;
        end


        function [] = ploters(~,fig,t,x)
            fig.Visible = 'on';
            plot(fig,t,x);
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.BadanysygnaDropDown.Enable = 'on';
        end

        % Button pushed function: ImportujsygnaButton
        function ImportujsygnaButtonPushed(app, event)
            if(app.BadanysygnaDropDown.Enable == "on")
                app.x = [];
                app.t = [];
                datas = load(app.file);
            else
                app.x = [];
                app.t = [];
                app.file = uigetfile('*.mat');
                try 
                    datas = load(app.file);
                catch
                    uialert(app.UIFigure,'Zaimportowano plik o złym rozszerzeniu lub niepoprawnej definicji sygnału','Komunikat','Icon','warning');   
                end
            end
            
            try
            app.x = datas.x;
            app.t = datas.t;
            app.SliceButton.Enable = 'on';
            app.ResetButton.Enable = 'on';
            app.cleans;
            app.BadanysygnaDropDown.Enable = 'off';
            app.ImportujsygnaButton.Enable = 'off';
            app.SygnademonstracyjnyButton.Enable = 'off';
            app.SygnawasnyButton.Enable = 'off';
            uialert(app.UIFigure,'Zaimportowano dane','Komunikat','Icon','success');
            catch
%             uialert(app.UIFigure,'Błąd importowania sygnału - sprawdź poprawność danych','Komunikat','Icon','warning');   
            end
            
            
        end

        % Button pushed function: WyjdButton
        function WyjdButtonPushed(app, event)
            close(app.UIFigure) ;     
        end

        % Button pushed function: SliceButton
        function SliceButtonPushed(app, event)
            slice_num = 4;
            onlay = app.NakadkowanieSpinner.Value;
            t_slice = app.slice(app.t, slice_num, onlay);
            sub_sigs = app.slice(app.x, slice_num, onlay);
           
            [app.det, app.tab_data] = app.determine(app.x, sub_sigs,0.05, slice_num);
            
            if(app.det == true)
                app.StacjonarnyLamp.Enable = 'on';
                app.NiestacjonarnyLamp.Enable = 'off';
            else
                app.StacjonarnyLamp.Enable = 'off';
                app.NiestacjonarnyLamp.Enable = 'on';
            end
            
            app.ploters(app.UIAxes,app.t,app.x)
            app.UIAxes.XLim = [app.t(1) app.t(end)];
            app.ploters(app.UIAxes5,t_slice(1,:),sub_sigs(1,:))
            app.UIAxes5.XLim = [t_slice(1,1) t_slice(1,end)];
            app.ploters(app.UIAxes4,t_slice(2,:),sub_sigs(2,:))
            app.UIAxes4.XLim = [t_slice(2,1) t_slice(2,end)];
            app.ploters(app.UIAxes3,t_slice(3,:),sub_sigs(3,:))
            app.UIAxes3.XLim = [t_slice(3,1) t_slice(3,end)];
            app.ploters(app.UIAxes2,t_slice(4,:),sub_sigs(4,:))
            app.UIAxes2.XLim = [t_slice(4,1) t_slice(4,end)];
            app.ResetButton.Enable = 'on';
            app.UITable.Data = app.tab_data;
           
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SposbwyborusygnauButtonGroup ;
            app.SliceButton.Enable = 'off';
            app.ResetButton.Enable = 'off';
            app.ImportujsygnaButton.Enable = 'on';
            selBut = app.SposbwyborusygnauButtonGroup.SelectedObject;
            if(selBut == app.SygnademonstracyjnyButton) 
            app.BadanysygnaDropDown.Enable = 'on';
            end
            app.SygnademonstracyjnyButton.Enable = 'on';
            app.SygnawasnyButton.Enable = 'on';
            app.cleans;
            app.tab_data = [];
            app.UITable.Data = app.tab_data;
            
        end

        % Value changed function: BadanysygnaDropDown
        function BadanysygnaDropDownValueChanged(app, event)
            value = app.BadanysygnaDropDown.Value;
            if(value == "Poliharmoniczny")
                app.file = "poli.mat";
            elseif(value == "Sinus")
                app.file = "sinus.mat";
            elseif(value == "Stały")
                app.file = "const.mat";
            elseif(value == "Wielomian")
                app.file = "polynomial.mat";
            else 
                app.file = "stochastic.mat";
            end
        end

        % Selection changed function: SposbwyborusygnauButtonGroup
        function SposbwyborusygnauButtonGroupSelectionChanged(app, event)
            selectedButton = app.SposbwyborusygnauButtonGroup.SelectedObject;
            if(selectedButton == app.SygnademonstracyjnyButton)
                app.BadanysygnaDropDown.Enable = 'on';
                app.file = app.bufor;
            else
                app.BadanysygnaDropDown.Enable = 'off';
                app.bufor = app.file;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BadaniestacjonarnocisygnauMenu = uimenu(app.UIFigure);
            app.BadaniestacjonarnocisygnauMenu.Text = 'Badanie stacjonarności sygnału';
            app.StacjonarnyLampLabel = uilabel(app.UIFigure);
            app.StacjonarnyLampLabel.Text = 'Stacjonarny';
            app.StacjonarnyLamp = uilamp(app.UIFigure);
            app.StacjonarnyLamp.Enable = 'off';
            app.NiestacjonarnyLampLabel = uilabel(app.UIFigure);
            app.NiestacjonarnyLampLabel.Text = 'Niestacjonarny';
            app.NiestacjonarnyLamp = uilamp(app.UIFigure);
            app.NiestacjonarnyLamp.Enable = 'off';
            app.SliceButton = uibutton(app.UIFigure, 'push');
            app.SliceButton.ButtonPushedFcn = createCallbackFcn(app, @SliceButtonPushed, true);
            app.SliceButton.Enable = 'off';
            app.SliceButton.Text = 'Slice';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BadanysygnaTab = uitab(app.TabGroup);
            app.BadanysygnaTab.Title = 'Badany sygnał';
            app.UIAxes = uiaxes(app.BadanysygnaTab);
            title(app.UIAxes, 'Analizowany sygnał')
            xlabel(app.UIAxes, 'Czas [s]')
            ylabel(app.UIAxes, {'Amplituda'; ''})
            app.UIAxes.Visible = 'off';
            app.Fragment1Tab = uitab(app.TabGroup);
            app.Fragment1Tab.Title = 'Fragment 1';
            app.UIAxes5 = uiaxes(app.Fragment1Tab);
            title(app.UIAxes5, 'Fragment1')
            xlabel(app.UIAxes5, 'Czas [s]')
            ylabel(app.UIAxes5, 'Amplituda')
            app.UIAxes5.Visible = 'off';
            app.Fragment2Tab = uitab(app.TabGroup);
            app.Fragment2Tab.Title = 'Fragment 2';
            app.UIAxes4 = uiaxes(app.Fragment2Tab);
            title(app.UIAxes4, 'Fragment2')
            xlabel(app.UIAxes4, 'Czas [s]')
            ylabel(app.UIAxes4, 'Amplituda')
            app.UIAxes4.Visible = 'off';
            app.Fragment3Tab = uitab(app.TabGroup);
            app.Fragment3Tab.Title = 'Fragment 3';
            app.UIAxes3 = uiaxes(app.Fragment3Tab);
            title(app.UIAxes3, 'Fragment3')
            xlabel(app.UIAxes3, 'Czas [s]')
            ylabel(app.UIAxes3, 'Amplituda')
            app.UIAxes3.Visible = 'off';
            app.Fragment4Tab = uitab(app.TabGroup);
            app.Fragment4Tab.Title = 'Fragment 4';
            app.UIAxes2 = uiaxes(app.Fragment4Tab);
            title(app.UIAxes2, 'Fragment4')
            xlabel(app.UIAxes2, 'Czas [s]')
            ylabel(app.UIAxes2, 'Amplituda')
            app.UIAxes2.Visible = 'off';
            app.ImportujsygnaButton = uibutton(app.UIFigure, 'push');
            app.ImportujsygnaButton.ButtonPushedFcn = createCallbackFcn(app, @ImportujsygnaButtonPushed, true);
            app.ImportujsygnaButton.Text = 'Importuj sygnał';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.WyjdButton = uibutton(app.UIFigure, 'push');
            app.WyjdButton.ButtonPushedFcn = createCallbackFcn(app, @WyjdButtonPushed, true);
            app.WyjdButton.Text = 'Wyjdź';
            app.NakadkowanieSpinnerLabel = uilabel(app.UIFigure);
            app.NakadkowanieSpinnerLabel.Text = 'Nakładkowanie';
            app.NakadkowanieSpinner = uispinner(app.UIFigure);
            app.NakadkowanieSpinner.Step = 0.05;
            app.NakadkowanieSpinner.Limits = [0 0.99];
            app.NakadkowanieSpinner.Value = 0.25;
            app.BadanysygnaDropDownLabel = uilabel(app.UIFigure);
            app.BadanysygnaDropDownLabel.Text = 'Badany sygnał';
            app.BadanysygnaDropDown = uidropdown(app.UIFigure);
            app.BadanysygnaDropDown.Items = {'Poliharmoniczny', 'Sinus', 'Stały', 'Wielomian', 'Stochastyczny'};
            app.BadanysygnaDropDown.ValueChangedFcn = createCallbackFcn(app, @BadanysygnaDropDownValueChanged, true);
            app.BadanysygnaDropDown.Value = 'Poliharmoniczny';
            app.BadadniestacjonarnocisygnauLabel = uilabel(app.UIFigure);
            app.BadadniestacjonarnocisygnauLabel.Text = 'Badadnie stacjonarności sygnału';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Badany Sygnał'; 'Fragment 1'; 'Fragment 2'; 'Fragment 3'; 'Fragment 4'};
            app.UITable.RowName = {'Średnia'; 'RMS'; 'Wariancja'; 'Odchylenie'; 'Skośność'; 'Kurtoza '; ''};
            app.SposbwyborusygnauButtonGroup = uibuttongroup(app.UIFigure);
            app.SposbwyborusygnauButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SposbwyborusygnauButtonGroupSelectionChanged, true);
            app.SposbwyborusygnauButtonGroup.Title = 'Sposób wyboru sygnału';
            app.SygnademonstracyjnyButton = uiradiobutton(app.SposbwyborusygnauButtonGroup);
            app.SygnademonstracyjnyButton.Text = 'Sygnał demonstracyjny';
            app.SygnademonstracyjnyButton.Value = true;
            app.SygnawasnyButton = uiradiobutton(app.SposbwyborusygnauButtonGroup);
            app.SygnawasnyButton.Text = 'Sygnał własny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end